classdef Data_Acquisition_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcousticPlateReaderUIFigure    matlab.ui.Figure
        TransducerButtonGroup          matlab.ui.container.ButtonGroup
        L2214vXButton                  matlab.ui.control.RadioButton
        BeamTypeButtonGroup            matlab.ui.container.ButtonGroup
        L104vButton                    matlab.ui.control.RadioButton
        L2214vButton                   matlab.ui.control.RadioButton
        xAMButton                      matlab.ui.control.RadioButton
        pAMButton                      matlab.ui.control.RadioButton
        xBmodeButton                   matlab.ui.control.RadioButton
        pBmodeButton                   matlab.ui.control.RadioButton
        PlateParameters                matlab.ui.container.Panel
        StartingWellPositionEditField  matlab.ui.control.EditField
        StartingWellPositionEditFieldLabel  matlab.ui.control.Label
        NumberofcolumnsEditField       matlab.ui.control.NumericEditField
        NumberofcolumnsEditFieldLabel  matlab.ui.control.Label
        NumberofrowsEditField          matlab.ui.control.NumericEditField
        NumberofrowsLabel              matlab.ui.control.Label
        SaveFileDirectory              matlab.ui.container.Panel
        Vantage440462supportedLabel    matlab.ui.control.Label
        SelectVantageDirectoryButton   matlab.ui.control.Button
        SavenameEditField              matlab.ui.control.EditField
        SavenameEditFieldLabel         matlab.ui.control.Label
        SaveInfoLabel                  matlab.ui.control.Label
        ScanParameters                 matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        NumField4Label                 matlab.ui.control.Label
        NumField4                      matlab.ui.control.NumericEditField
        NumField3                      matlab.ui.control.NumericEditField
        NumField3Label                 matlab.ui.control.Label
        NumField2                      matlab.ui.control.NumericEditField
        NumField2Label                 matlab.ui.control.Label
        NumField1                      matlab.ui.control.NumericEditField
        NumField1Label                 matlab.ui.control.Label
        ScanTypeDropDown               matlab.ui.control.DropDown
        ScanTypeDropDownLabel          matlab.ui.control.Label
        ConfirmStartScanButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        SaveDirectory;
        SaveName;
        ScanType;
        Voltages;
        P; %Matlab structure that we will populate for the DAQ script to use and scan
    end
    
    methods (Access = private)
        
        function activate(app)
            % This function runs the Verasonics activate.p script if it
            % hasn't already been run during the current MATLAB session
            persistent called
            if isempty(called)
                cd(app.SaveDirectory);
                activate;
                called = true;
            end
        end
        
        function update_save(app)
            %This function updates the SaveInfolabel with the information stored in the properties.
            % It will also update the Ramp Label with the Voltages if needed
            app.SaveName = app.SavenameEditField.Value;
            string_save = ['Save Location:' newline '    '];
            if valid_save_directory(app)
                string_save = [string_save fullfile(app.SaveDirectory, 'Data', [datestr(now, 'yymmdd')])];
            else
                string_save = [string_save '*Not yet selected*'];
            end
            app.SaveInfoLabel.Text = string_save;
        end
        
        function update_ramp(app)
            %Update the Ramp Values
            if strcmp(app.ScanType, 'Voltage Ramp') || strcmp(app.ScanType, 'Collapse Ramp')
                app.Voltages = app.NumField1.Value:app.NumField3.Value:app.NumField2.Value;
                app.Label.Text = convertStringsToChars(strjoin(['Ramp Voltage Values:' newline string(app.Voltages)]));
            else
                app.Label.Text = '';
                app.Voltages = [app.NumField3.Value];
            end
        end
        
        function update_scan(app)
            %Code from the update button, now applied to value changed
            %callbacks
            if valid_voltages(app)
                app.ScanType = app.ScanTypeDropDown.Value;
                update_ramp(app);
            end
        end
        
        function result = valid_save_directory(app)
            %This is a helper function that is used to check if the user
            %has inputted a save directory. It returns true if the user
            %has specified a valid save directory and save name, false if they
            %haven't specified one
            if isempty(app.SaveDirectory) || isempty(app.SaveName)
                result = false;
                return;
            end
            if ~isempty(regexp(app.SaveName, '[/\*:?"<>|]', 'once'))
                raise_error(app, 'Invalid File Name Provided')
                result = false;
                return;
            end
            result = true;
        end
        
        function result = valid_well_parameters(app)
            %This function will check if the starting well, the row
            %numbers, and the row columns are valid entries. It will return
            % true if the parameters are valid, false otherwise
            start_well = app.StartingWellPositionEditField.Value;
            error_msg = [start_well ' is an invalid starting well value'];
            result = false; %initialize result to false, it will change to true when all the tests are passed
            
            %Check first character to see if it is valid character for the
            %row
            if ~ismember(start_well(1), 'ABCDEFGH')
                result = false;
                raise_error(app, error_msg);
                return;
            end
            
            %check the the column number to see if it is valid
            start_column_num = str2double(start_well(2:end));
            if isnan(start_column_num)
                raise_error(app, error_msg);
                return
            end
            if ~ismember(start_column_num, 1:1:12)
                raise_error(app, error_msg);
                return
            end
            
            %check the number of columns that will be used to see if it is
            %valid
            num_columns = app.NumberofcolumnsEditField.Value;
            num_rows = app.NumberofrowsEditField.Value;
            if ~ismember(num_columns, 1:1:13 - start_column_num)
                raise_error(app, 'Invalid Number of Columns');
                return
            end
            
            %check the number of rows that will be used to see if it is
            %valid
            start_row_num = find('ABCDEFGH'==start_well(1));
            if ~ismember(num_rows, 1:1:9 - start_row_num)
                raise_error(app, 'Invalid Number of Rows');
                return
            end
            result = true;
        end
        
        function result = valid_voltages(app)
            %This function will raise an error and return false if there
            %are any invalid voltage entries. Otherwise, it will return
            %true
            
            %Raise an error if the voltages are negative
            if app.NumField1.Value < 0
                raise_error(app, [app.NumField1.Value ' is an invalid voltage value']);
                result = false;
                return;
            end
            if app.NumField2.Value < 0
                raise_error(app, [app.NumField2.Value ' is an invalid voltage value']);
                result = false;
                return;
            end
            if app.NumField3.Value < 0
                raise_error(app, [app.NumField3.Value ' is an invalid voltage value']);
                result = false;
                return;
            end
            if app.NumField4.Value < 0
                raise_error(app, [app.NumField4.Value ' is an invalid voltage value']);
                result = false;
                return;
            end
            
            %checks if the starting voltage is greater than the final
            %voltage for the ramp options
            if (strcmp(app.ScanType, 'Voltage Ramp') || strcmp(app.ScanType, 'Collapse Ramp')) && (app.NumField1.Value > app.NumField2.Value)
                raise_error(app, 'Invalid Start and Final Voltage values')
                result = false;
                return;
            end
            
            result = true;
        end
        
        function raise_error(app, message)
            %This is a helper function that opens up a new window and displays error messages to the user
            h=errordlg(message,'Error');
            set(h, 'WindowStyle', 'modal');
            uiwait(h);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ScanType = app.ScanTypeDropDown.Value;
        end

        % Value changed function: ScanTypeDropDown
        function ScanTypeDropDownValueChanged(app, event)
            %This function will change the edit fields for the voltage
            %depending on what option is selected in the Scan Type Drop
            %Down
            value = app.ScanTypeDropDown.Value;
            app.ScanType = value;
            if strcmp(value,'Pre-post Collapse')
                app.NumField1Label.Text = 'Pre-collapse Voltage (V)';
                app.NumField1.Value = 25;
                app.NumField2Label.Text = 'Collapse Voltage (V)';
                app.NumField2.Value = 25;
                app.NumField3Label.Text = 'Post-collapse Voltage (V)';
                app.NumField3.Value = 25;
                app.NumField4.Visible = false;
                app.NumField4Label.Visible = false;
            elseif strcmp(value,'Voltage Ramp') || strcmp(value,'Collapse Ramp')
                app.NumField1Label.Text = 'Start Voltage (V)';
                app.NumField1.Value = 2;
                app.NumField2Label.Text = 'Final Voltage (V)';
                app.NumField2.Value = 25;
                app.NumField3Label.Text = 'Step (V)';
                app.NumField3.Value = 1;
                app.NumField4.Visible = true;
                app.NumField4Label.Visible = true;
                if strcmp(value,'Voltage Ramp')
                    app.NumField4Label.Text = 'Collapse Voltage (V)';
                    app.NumField4.Value = 25;
                end
                if strcmp(value,'Collapse Ramp')
                    app.NumField4Label.Text = 'Imaging Voltage (V)';
                    app.NumField4.Value = 1.6;
                end
                
            end
            update_scan(app);
        end

        % Callback function
        function UpdateScanPushed(app, event)
            if valid_voltages(app)
                app.ScanType = app.ScanTypeDropDown.Value;
                update_ramp(app);
            end
        end

        % Button pushed function: SelectVantageDirectoryButton
        function SelectVantageDirectoryButtonPushed(app, event)
            [app.SaveDirectory, ] = uigetdir;
            drawnow;
            figure(app.AcousticPlateReaderUIFigure)
            update_save(app);
        end

        % Button pushed function: ConfirmStartScanButton
        function ConfirmStartScanButtonPushed(app, event)
            if ~valid_well_parameters(app)
                return
            end
            if ~valid_save_directory(app)
                raise_error(app, 'Select a valid Save Directory')
                return
            end
            if ~valid_voltages(app)
                return
            end
            
            activate(app); % change to and activate Vantage directory
            
            Initial_Pos = app.StartingWellPositionEditField.Value; %Starting point
            
            app.P.savepath = [fullfile(app.SaveDirectory, 'Data', datestr(now, 'yymmdd')) '/']; % dir for scan
            app.P.saveDirName = app.SaveName; % dir name
            app.P.saveDirName1 = [datestr(now, 'yymmdd') '_']; % dir prefix
            app.P.seed = app.Voltages; % Voltge ramp range
            app.P.scantype = app.ScanType; % Provide scantype
            
            % Specify scan parameters
            if strcmp(app.ScanType,'Pre-post Collapse')
                %imaging done with axicon beams
                PostCollapse = app.NumField3.Value; % post-collapse voltage
                app.P.BC = app.NumField2.Value; % Parabolic collapse voltage % max(P.seed, 25)
                app.P.BI = 1.6; % Parabolic B-mode imaging voltage
            elseif strcmp(app.ScanType,'Voltage Ramp')
                %imaging done with axicon beams
                PostCollapse = max(app.P.seed); % post-collapse voltage
                app.P.BC = app.NumField4.Value; % Parabolic collapse voltage 
                app.P.BI = 1.6; % Parabolic B-mode imaging voltage
            elseif strcmp(app.ScanType,'Collapse Ramp')
                %Imaging is done with parabolic beams
                app.P.BC = 25; % Parabolic collapse voltage, compare 
                app.P.BI = app.NumField4.Value; % Parabolic B-mode imaging voltage
                PostCollapse = app.P.BI; %Not used, but set it the same as P.BI
            end
            
            app.P.xLines = app.NumberofrowsEditField.Value; % number of scanned lines in the x-direction
            app.P.zLines = app.NumberofcolumnsEditField.Value; % number of scanned lines in the z-direction
            
            % Clear base workspace, then assign variables into it
            evalin('base', 'clear');
            assignin('base', "Initial_Pos", Initial_Pos);
            assignin('base', "P", app.P);
            assignin('base', "PostCollapse", PostCollapse);
            
            
            app.ConfirmStartScanButton.Text = 'Running Scan';
            evalin('base', 'DAQ'); % call data acquisition script
            app.ConfirmStartScanButton.Text = 'Scan Complete';
        end

        % Callback function
        function UpdateSaveButtonPushed(app, event)
            update_save(app);
        end

        % Value changed function: SavenameEditField
        function SavenameEditFieldValueChanged(app, event)
            update_save(app);
        end

        % Value changed function: NumField1
        function NumField1ValueChanged(app, event)
            update_scan(app)
        end

        % Value changed function: NumField2
        function NumField2ValueChanged(app, event)
            update_scan(app)
        end

        % Value changed function: NumField3
        function NumField3ValueChanged(app, event)
            update_scan(app)
        end

        % Value changed function: NumField4
        function NumField4ValueChanged(app, event)
            update_scan(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AcousticPlateReaderUIFigure = uifigure('Visible', 'off');
            app.AcousticPlateReaderUIFigure.Name = 'Acoustic Plate Reader';
            app.ConfirmStartScanButton = uibutton(app.AcousticPlateReaderUIFigure, 'push');
            app.ConfirmStartScanButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmStartScanButtonPushed, true);
            app.ConfirmStartScanButton.Text = 'Confirm/Start Scan';
            app.ScanParameters = uipanel(app.AcousticPlateReaderUIFigure);
            app.ScanTypeDropDownLabel = uilabel(app.ScanParameters);
            app.ScanTypeDropDownLabel.Text = 'Scan Type';
            app.ScanTypeDropDown = uidropdown(app.ScanParameters);
            app.ScanTypeDropDown.Items = {'Pre-post Collapse', 'Voltage Ramp', 'Collapse Ramp'};
            app.ScanTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ScanTypeDropDownValueChanged, true);
            app.ScanTypeDropDown.Value = 'Pre-post Collapse';
            app.NumField1Label = uilabel(app.ScanParameters);
            app.NumField1Label.Text = 'Pre-collapse Voltage (V)';
            app.NumField1 = uieditfield(app.ScanParameters, 'numeric');
            app.NumField1.ValueChangedFcn = createCallbackFcn(app, @NumField1ValueChanged, true);
            app.NumField1.Value = 25;
            app.NumField2Label = uilabel(app.ScanParameters);
            app.NumField2Label.Text = 'Collapse Voltage (V)';
            app.NumField2 = uieditfield(app.ScanParameters, 'numeric');
            app.NumField2.ValueChangedFcn = createCallbackFcn(app, @NumField2ValueChanged, true);
            app.NumField2.Value = 25;
            app.NumField3Label = uilabel(app.ScanParameters);
            app.NumField3Label.Text = 'Post-collapse Voltage (V)';
            app.NumField3 = uieditfield(app.ScanParameters, 'numeric');
            app.NumField3.ValueChangedFcn = createCallbackFcn(app, @NumField3ValueChanged, true);
            app.NumField3.Value = 25;
            app.NumField4 = uieditfield(app.ScanParameters, 'numeric');
            app.NumField4.ValueChangedFcn = createCallbackFcn(app, @NumField4ValueChanged, true);
            app.NumField4.Visible = 'off';
            app.NumField4.Value = 25;
            app.NumField4Label = uilabel(app.ScanParameters);
            app.NumField4Label.Visible = 'off';
            app.NumField4Label.Text = 'Imaging Voltage (V)';
            app.Label = uilabel(app.ScanParameters);
            app.Label.Text = '';
            app.SaveFileDirectory = uipanel(app.AcousticPlateReaderUIFigure);
            app.SaveInfoLabel = uilabel(app.SaveFileDirectory);
            app.SaveInfoLabel.Text = {'Save Location:'; '    *Not yet selected*'; ''; ''};
            app.SavenameEditFieldLabel = uilabel(app.SaveFileDirectory);
            app.SavenameEditFieldLabel.Text = 'Save name';
            app.SavenameEditField = uieditfield(app.SaveFileDirectory, 'text');
            app.SavenameEditField.ValueChangedFcn = createCallbackFcn(app, @SavenameEditFieldValueChanged, true);
            app.SelectVantageDirectoryButton = uibutton(app.SaveFileDirectory, 'push');
            app.SelectVantageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVantageDirectoryButtonPushed, true);
            app.SelectVantageDirectoryButton.Text = 'Select Vantage Directory';
            app.Vantage440462supportedLabel = uilabel(app.SaveFileDirectory);
            app.Vantage440462supportedLabel.Text = 'Vantage 4.4.0 - 4.6.2 supported';
            app.PlateParameters = uipanel(app.AcousticPlateReaderUIFigure);
            app.NumberofrowsLabel = uilabel(app.PlateParameters);
            app.NumberofrowsLabel.Text = 'Number of rows';
            app.NumberofrowsEditField = uieditfield(app.PlateParameters, 'numeric');
            app.NumberofrowsEditField.Value = 8;
            app.NumberofcolumnsEditFieldLabel = uilabel(app.PlateParameters);
            app.NumberofcolumnsEditFieldLabel.Text = 'Number of columns';
            app.NumberofcolumnsEditField = uieditfield(app.PlateParameters, 'numeric');
            app.NumberofcolumnsEditField.Value = 12;
            app.StartingWellPositionEditFieldLabel = uilabel(app.PlateParameters);
            app.StartingWellPositionEditFieldLabel.Text = 'Starting Well Position';
            app.StartingWellPositionEditField = uieditfield(app.PlateParameters, 'text');
            app.StartingWellPositionEditField.Value = 'A1';
            app.BeamTypeButtonGroup = uibuttongroup(app.AcousticPlateReaderUIFigure);
            app.BeamTypeButtonGroup.Title = 'Beam Type';
            app.pBmodeButton = uiradiobutton(app.BeamTypeButtonGroup);
            app.pBmodeButton.Text = 'pBmode';
            app.xBmodeButton = uiradiobutton(app.BeamTypeButtonGroup);
            app.xBmodeButton.Text = 'xBmode';
            app.pAMButton = uiradiobutton(app.BeamTypeButtonGroup);
            app.pAMButton.Text = 'pAM';
            app.xAMButton = uiradiobutton(app.BeamTypeButtonGroup);
            app.xAMButton.Text = 'xAM';
            app.xAMButton.Value = true;
            app.TransducerButtonGroup = uibuttongroup(app.AcousticPlateReaderUIFigure);
            app.TransducerButtonGroup.Title = 'Transducer';
            app.L2214vButton = uiradiobutton(app.TransducerButtonGroup);
            app.L2214vButton.Text = 'L22-14v';
            app.L104vButton = uiradiobutton(app.TransducerButtonGroup);
            app.L104vButton.Text = 'L10-4v';
            app.L2214vXButton = uiradiobutton(app.TransducerButtonGroup);
            app.L2214vXButton.Text = 'L22-14vX';
            app.L2214vXButton.Value = true;
            % Show the figure after all components are created
            app.AcousticPlateReaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Acquisition_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AcousticPlateReaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcousticPlateReaderUIFigure)
        end
    end
end