classdef Data_Processing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SelectFileTab_2                matlab.ui.container.Tab
        FastPassButton                 matlab.ui.control.Button
        ConfirmButton                  matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        ImportevSeqdataButton          matlab.ui.control.Button
        InfoLabel                      matlab.ui.control.Label
        ImportmetadataButton           matlab.ui.control.Button
        SelectDirectoryButton          matlab.ui.control.Button
        PrePostCollapseTab             matlab.ui.container.Tab
        PrePostTree                    matlab.ui.container.CheckBoxTree
        PPReplicatesNode               matlab.ui.container.TreeNode
        PPavgAllNode                   matlab.ui.container.TreeNode
        PPavgSelectNode                matlab.ui.container.TreeNode
        PrePostIMDropDown              matlab.ui.control.DropDown
        ImagingModeDropDown_2Label     matlab.ui.control.Label
        PrePostCollapseFigure          matlab.ui.control.UIAxes
        VoltageSliderTab               matlab.ui.container.Tab
        VoltageSliderTree              matlab.ui.container.CheckBoxTree
        VSReplicatesNode               matlab.ui.container.TreeNode
        VSavgAllNode                   matlab.ui.container.TreeNode
        VSavgSelectNode                matlab.ui.container.TreeNode
        VoltageSliderIMDropDown        matlab.ui.control.DropDown
        ImagingModeDropDown_2Label_2   matlab.ui.control.Label
        VoltageSliderPanel             matlab.ui.container.Panel
        VoltagePlotTab                 matlab.ui.container.Tab
        VoltagePlotTree                matlab.ui.container.CheckBoxTree
        VPReplicatesNode               matlab.ui.container.TreeNode
        VPavgAllNode                   matlab.ui.container.TreeNode
        VPavgSelectedNode              matlab.ui.container.TreeNode
        VPGroupByNode                  matlab.ui.container.TreeNode
        LegendLocationDropDown         matlab.ui.control.DropDown
        LegendLocationDropDownLabel    matlab.ui.control.Label
        GroupByDropDown                matlab.ui.control.DropDown
        GroupByDropDownLabel           matlab.ui.control.Label
        VoltagePlot                    matlab.ui.control.UIAxes
        MicroplatePlotTab              matlab.ui.container.Tab
        MicroplateIMDropDown           matlab.ui.control.DropDown
        ImagingModeDropDownLabel_2     matlab.ui.control.Label
        MicroplatePlotTree             matlab.ui.container.CheckBoxTree
        MPReplicatesNode               matlab.ui.container.TreeNode
        MPavgAllNode                   matlab.ui.container.TreeNode
        MPavgSelectNode                matlab.ui.container.TreeNode
        Microplateplot                 matlab.ui.control.UIAxes
        MicroplateSliderTab            matlab.ui.container.Tab
        MicroplateSliderTree           matlab.ui.container.CheckBoxTree
        MSReplicatesNode               matlab.ui.container.TreeNode
        MSavgAllNode                   matlab.ui.container.TreeNode
        MSavgSelectNode                matlab.ui.container.TreeNode
        MicroSlideIMDropDown           matlab.ui.control.DropDown
        ImagingModeDropDownLabel       matlab.ui.control.Label
        MicroplateSlider               matlab.ui.control.Slider
        VoltageSliderLabel             matlab.ui.control.Label
        MicroplateSliderViewer         matlab.ui.control.UIAxes
        SaveDataTab                    matlab.ui.container.Tab
        SaveButton                     matlab.ui.control.Button
        Tree                           matlab.ui.container.CheckBoxTree
        FiguresNode                    matlab.ui.container.TreeNode
        PrepostcollapseNode            matlab.ui.container.TreeNode
        VoltageSliderNode              matlab.ui.container.TreeNode
        VoltagePlotNode                matlab.ui.container.TreeNode
        MicroplatePlotNode             matlab.ui.container.TreeNode
        MicroplateSliderNode           matlab.ui.container.TreeNode
        evSeqPlotNode                  matlab.ui.container.TreeNode
        DataNode                       matlab.ui.container.TreeNode
        dImiprepostcollapsedataNode    matlab.ui.container.TreeNode
        SampCNRsmicroplatesliderdataNode  matlab.ui.container.TreeNode
        maxs_AMMicroplateplotdataNode  matlab.ui.container.TreeNode
        PscaninformationNode           matlab.ui.container.TreeNode
        dImgsvoltagesliderdataNode     matlab.ui.container.TreeNode
        evSeqPlotTab                   matlab.ui.container.Tab
        evSeqTree                      matlab.ui.container.CheckBoxTree
        evSeqReplicatesNode            matlab.ui.container.TreeNode
        evSeqavgAllNode                matlab.ui.container.TreeNode
        evSeqavgSelectedNode           matlab.ui.container.TreeNode
        evSeqPlot                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ScanType;
        Data_directory = '*Not yet imported*';
        MetadataFileName = '*Not yet imported*';
        MetadataPathName = '';
        Parameters;
        EvSeqTable;
        EvSeqFileName; 
        EvSeqPathName;
        dImi; % pre-post collapse images
        SampCNRs; %quantified values of wells from ROI
        maxs_AM; % max xAM signal achieved at any voltage for each well
        P; %struct with scan information among other things
        Colorm; %color mapp for prepost collapse
        PlateSize; %dimensions of the plate used
        N_Frames; %number of frames
        ScaleFactors;
        Imaging_mode; %either 1 or 2
        Full_Map; % maps well locations to another map that maps parameters to values
        % example full_map {A1: {color: blue, size: large, weight: 100}, A2: {color: red, size: small, weight:50}}
        dImgs % Rescaled and reshaped pre-post collapse difference images
        sample_depth % depth range of image to display
        noise_slice % depth range of noise slice
        Zi % Z indices of image
        dImis % unpacked dImi array
    end

    methods (Access = private)

        function update(app)
            %This function updates the Infolabel with the information stored in the properties.
            % It will also update the Ramp Label with the Voltages if needed
            string1 = ['File Directory:' newline '    ' app.Data_directory];
            string2 = ['Metadata File:' newline '    ' app.MetadataPathName '\' app.MetadataFileName];
            string3 = ['evSeq File:' newline '    ' app.EvSeqPathName '\' app.EvSeqFileName];
            app.InfoLabel.Text = [string1 newline string2 newline string3];
            if strcmp(app.ScanType, 'Voltage Ramp') || strcmp(app.ScanType, 'Collapse Ramp')
                app.RampLabel.Text = convertStringsToChars(strjoin(['Voltage Values:' newline string(app.NumField1.Value:app.NumField3.Value:app.NumField2.Value)]));
            end
        end

        function full_map = extract_metadata(app)
            %This function will extract information for each well from the
            %metadata. It will also raise errors and return a blank if
            %there are incompatability issues with the metadata file

            %It will return the full_map which maps well locations to
            %another map that maps parameters to values
            % example full_map {A1: {color: blue, size: large, weight: 100}, A2: {color: red, size: small, weight:50}}

            %file provided by the user
            if isempty(app.MetadataPathName) || isempty(app.MetadataFileName)
                return %don't proceed if the user has not provided a metadata file
            end
            metadata_cell = readcell([app.MetadataPathName '\' app.MetadataFileName]);
            size_cell = size(metadata_cell);
            wells_used = metadata_cell(2:size_cell(1)); %take all the elements in the well_used column
            full_map = containers.Map;

            %check that each of the wells are unique
            if length(wells_used) ~= length(unique(wells_used))
                raise_error(app, 'There exists a duplicate well');
                return;
            end

            transpose_metadata = metadata_cell'; %take transpose of metadata cell. Makes the code easier
            parameters = transpose_metadata(2:size_cell(2)); %take all the parameters
            app.Parameters = parameters;

            %iterate through the wells, check each one, and populate full map
            %The keys for full_map are strings for the wells (ex. A1), the
            %values will be maps
            start_index = size_cell(2)+2;
            for well = wells_used
                well_string = well{1}; %Have to do this so you have a string instead of a cell containing a string
                error_msg = [well_string 'is an invalid well name'];

                %check if the well string starts with letters A - H
                if ~ismember(well_string(1), 'ABCDEFGH')
                    raise_error(app, error_msg);
                    full_map = containers.Map;
                    return;
                end

                %check if the well strings ends with numbers 1 - 12
                column_num = str2double(well_string(2:end));
                if isnan(column_num)
                    raise_error(app, error_msg);
                    full_map = containers.Map;
                    return
                end
                if ~ismember(column_num, 1:1:12)
                    raise_error(app, error_msg);
                    full_map = containers.Map;
                    return
                end

                %populate the map
                well_values = transpose_metadata(start_index:start_index + size_cell(2)-2);
                full_map(well_string) = containers.Map(parameters, well_values);
            end
            app.Full_Map = full_map;
        end

        function raise_error(app, message)
            %This is a helper function that opens up a new window and displays error messages to the user
            h=errordlg(message,'Error');
            set(h, 'WindowStyle', 'modal');
            uiwait(h);
        end

        function plot_prepost_collapse(app)
            %This function plots the pre post collapse image on the prepost
            %collapse tab.

            im_mode = populate_imaging_mode(app, app.PrePostIMDropDown.Value);
            assignin('base','zz_dimi',app.dImi);
            m = montage(squeeze(app.dImgs(:,:,:,16,1,:)),'Size',app.PlateSize,'Parent',app.PrePostCollapseFigure);
% dImgs dimensions: zs, xs, colors, pressures, imaging mode, wells
        end

        function plot_voltageramp_slider(app)
            %This function plots the voltage slider on the voltage slider tab.
            im_mode = populate_imaging_mode(app, app.VoltageSliderIMDropDown.Value);
            % create montage arrays
            montages_xAM = []; % initalize montage images array
            montages_Bmode = []; % initalize montage images array
            size_dImis = size(app.dImi);
            for pressure = 1:size_dImis(3)
                montage_xAM = montage(squeeze(app.dImgs(:,:,:,pressure,1,:)),'Size',app.PlateSize); % create a montage image
                montages_xAM = cat(4,montages_xAM,montage_xAM.CData); % add montage image to montage array
                montage_Bmode = montage(squeeze(app.dImgs(:,:,:,pressure,2,:)),'Size',app.PlateSize); % create a montage image
                montages_Bmode = cat(4,montages_Bmode,montage_Bmode.CData); % add montage image to montage array
            end
            % visualize one montage per pressure with sliceViewer
            if im_mode == 1
                sliceViewer(permute(montages_xAM,[1 2 4 3]), 'Colormap', hot(256), 'Parent',app.VoltageSliderPanel);
            end
            if im_mode == 2
                sliceViewer(permute(montages_Bmode,[1 2 4 3]), 'Colormap', bone, 'Parent',app.VoltageSliderPanel);
            end
        end

        function plot_microplate_plot(app)
            %This function plots the microplate plot on the microplate plot
            %tab
            im_mode = populate_imaging_mode(app, app.MicroplateIMDropDown.Value);
            app.maxs_AM = evalin('base', 'maxs_AM');
            UImicroplateplot(app.Microplateplot, app.maxs_AM(:,:,im_mode));
        end

        function plot_microplate_slider(app)
            %This function plots the microplate slider on the microplate
            %slider tab
            im_mode = populate_imaging_mode(app, app.MicroSlideIMDropDown.Value);
            value = round(app.MicroplateSlider.Value);
            UImicroplateplot(app.MicroplateSliderViewer, app.SampCNRs(:,:,value,im_mode));

            %controls for microplate slider
            %             app.MicroplateSlider.Limits = [10 15];%[app.P.seed(1) app.P.seed(end)];
            %             app.MicroplateSlider.MajorTicks = [10, 11, 12, 13, 14, 15];%app.P.seed;
            %             app.MicroplateSlider.MajorTickLabels = string([10, 11, 12, 13, 14, 15]); %string(app.P.seed);
        end
        
        function group_map = make_groups_map(app, variables)
            %Given variables from the metadata file provided, this
            %function makes a map that maps the unique variable values to
            %their well values.
            group_map = containers.Map;
            metadata_table = readcell([app.MetadataPathName '\' app.MetadataFileName]);
            metadata_size = size(metadata_table);
            variable_columns = {};
            n_vars = size(variables);
            variable_names = metadata_table(1,:);
            wells_column = metadata_table(:,contains(variable_names,'Well'));
            for i = 1:n_vars(2) %populate variable_columns
                variable_index = contains(variable_names,variables{i});
                new_column = metadata_table(:,variable_index);
                if isa(new_column,'double') %convert mat columns to cells if there are any
                    new_column = sprintfc('%d',new_column);
                end
                variable_columns{1,end+1} = new_column;
            end
            
            %populate group map
            for i = 1:metadata_size(1)-1 
                key = ''; %key will contain parameters in string format
                %make key string out of parameters and populate row
                for var_n = 1:n_vars(2)
                    value = string(variable_columns{var_n}{i+1});
                    key = append(key, ', ', string(variables{var_n}), ': ', value);
                    %key = [key ', ' string(variables{var_n}) ': ' value];
                end
%                 key = key(3:end); %remove first two characters (, )
                if ~any(strcmp(keys(group_map),key)) 
                    %if we have a new key, add it to the map
                    group_map(key) = convertCharsToStrings(wells_column{i+1});
                else
                    group_map(key) = [group_map(key) convertCharsToStrings(wells_column{i+1})];
                end
            end
        end

        function group_map = make_group_map(app, variable)
            %Given a variable from the metadata file provided, this
            %function makes a map that maps the unique variable values to
            %their well values.
            group_map = containers.Map;
            metadata_table = readtable([app.MetadataPathName '\' app.MetadataFileName]);
            metadata_size = size(metadata_table);
            %variable_column = metadata_table.(variable); 
            
            
            %matrix
            group_map_1 = containers.Map;
            metadata_table_1 = readcell([app.MetadataPathName '\' app.MetadataFileName]);
            variable_names_1 = metadata_table_1(1,:);
            variable_index = contains(variable_names_1,variable);
            variable_column_1 = metadata_table_1(:,variable_index); 
            variable_column = variable_column_1; 
            if isa(variable_column_1,'double') %convert mat columns to cells if there are any
                variable_column_1 = sprintfc('%d',variable_column_1);
            end
            if isa(variable_column,'double') %convert mat columns to cells if there are any
                variable_column = sprintfc('%d',variable_column);
            end
            wells_column = metadata_table.('Well');
            wells_column_1 = metadata_table_1(:,contains(variable_names_1,'Well'));
            for i = 1:metadata_size(1) %iterate through the rows of the metadata file
                if ~any(strcmp(keys(group_map),variable_column{i})) %if variable_column(i) not in parameter_map.keys
                    assignin('base',"zz_variable_column",variable_column);
                    assignin('base',"zz_variable_column_1",variable_column_1);
                    assignin('base',"zz_group_map",group_map);
                    assignin('base',"zz_group_map_1",group_map_1);
                    assignin('base',"zz_wells_column_1",wells_column_1);
                    assignin('base',"zz_wells_column",wells_column);
                    assignin('base',"zz_i",i);
                    group_map(variable_column{i}) = convertCharsToStrings(wells_column{i});%add to map {key = variable_column(i): value = wells_column(i)}
                    %group_map_1(variable_column_1{i}) = convertCharsToStrings(wells_column_1{i+1});%add to map
                else
                    group_map(variable_column{i}) = [group_map(variable_column{i}) convertCharsToStrings(wells_column{i})];
                    %group_map_1(variable_column_1{i}) = [group_map_1(variable_column_1{i}) convertCharsToStrings(wells_column_1{i})];
                end
            end
        end

        function plot_voltage_line(app)
            %This function plots the line plot of the average voltages on the
            %Voltage line plot slide if the scan type is voltage ramp or collapse. If the scantype is
            % the pre-post collapse, it will plot a bar graph. It will also groups wells together
            % based on what the user selects for.
            reset(app.VoltagePlot); %delete what was previously on the VoltagePlot
            value = app.GroupByDropDown.Value;
            SampCNRs_size = size(app.SampCNRs); %need this for the rows and columns
            master_table = array2table(zeros(SampCNRs_size(3), 1)); %initialize the table with one column of zeroes
            if strcmp(value, 'Rows')
                %master_table = array2table(zeros(app.SampCNRs(1), 1)); %initialize the table with one column of zeroes
                for row_num = 1:SampCNRs_size(1)
                    table_col = {};
                    for voltage = 1:SampCNRs_size(3)
                        table_col = [table_col; mean(app.SampCNRs(row_num,:,voltage,1))] %add new element to column element
                    end
                    master_table.(['Row ' num2str(row_num)]) = table_col; %add the column to the table
                end
                master_table = removevars(master_table,{'Var1'}); %delete the extra column of zeroes that was made
            end

            if strcmp(value, 'Columns')
                %master_table = array2table(zeros(SampCNRs_size(2), 1)); %initialize the table with one column of zeroes
                for col_num = 1:SampCNRs_size(2)
                    table_col = {};
                    for voltage = 1:SampCNRs_size(3)
                        table_col = [table_col; mean(app.SampCNRs(:,col_num,voltage,1))]; %add new element to column element
                    end
                    master_table.(['Column ' num2str(col_num)]) = table_col; %add the column to the table
                end
                master_table = removevars(master_table,{'Var1'}); %delete the extra column of zeroes that was made
            end

            if ~strcmp(value, 'Columns') && ~strcmp(value, 'Rows') %it will be one the metadata parameters
                master_table = array2table(zeros(SampCNRs_size(3), 1)); %initialize the table with one column of zeroes
                group_map = make_group_map(app, value); %make the group map with the helper function
                group_map_keys = group_map.keys;
                key_size = size(group_map.keys);
                for key_num = 1:key_size(2) %for every group
                    group = group_map(group_map_keys{key_num});
                    group_size = size(group);
                    group_matrix = [];
                    for i = 1:group_size(2) %for well in group
                        well_column = [];
                        well = char(group(i));
                        [row_num, col_num] = wellstring2coords(app, well);
                        for voltage = 1:SampCNRs_size(3) %for every voltage
                            well_column = [well_column app.SampCNRs(row_num,col_num,voltage,1)];
                        end
                        group_matrix = [group_matrix; well_column];
                    end
                    master_table.(group_map_keys{key_num}) = mean(group_matrix',2); %add the column to the table
                end
                master_table = removevars(master_table,{'Var1'}); %delete the extra column of zeroes that was made
            end

            variables = master_table.Properties.VariableNames;
            variables_size = size(variables);
            if strcmp(app.ScanType, 'Pre-post Collapse') %create bar graph
                values = []; %values for bar graph
                for column_num = 1:width(master_table)
                    value_pair = cell2mat(master_table.(variables{column_num})(1:2)'); %get the values for a specific column
                    % and store it in the pair [pre-collapse post-collapse] array
                    values = [values; value_pair];
                end
                line_bar_plot = bar(app.VoltagePlot, categorical(variables), values); %set voltage plot to bar graph
                set(line_bar_plot, 'DisplayName', ['Pre-collapse','Post-collapse']);
                legend(app.VoltagePlot, 'Location','eastoutside');
                return;
            else %create a line plot
                hold(app.VoltagePlot,'on')
                for i = 1:variables_size(2)
                    assignin('base',"zzz_index",i);
                    assignin('base',"zzz_variables",variables);
                    assignin('base',"zzz_master_table",master_table);
                    values = master_table.(variables{i});
                    if isa(values, 'cell')
                        values = cell2mat(values);
                    end
                    line_bar_plot = plot(app.VoltagePlot, 1:SampCNRs_size(3), values, 'DisplayName',variables{i});
                    xlabel(app.VoltagePlot, 'Voltage (V)');
                    ylabel(app.VoltagePlot, 'Signal Intensity');
                    legend(app.VoltagePlot, 'Location','eastoutside');
                end
                hold(app.VoltagePlot,'off')
            end
        end

        function imaging_mode = populate_imaging_mode(app, value)
            %later move this function to a .m file? doesn't mave to be i
            %helper function that helps set variables based on imaging_mode
            %dropdown
            if strcmp(value, 'xAM')
                imaging_mode = 1;
            end
            if strcmp(value, 'B mode')
                imaging_mode = 2;
            end
        end
        
        function populate_trees(app)
            %populates the trees with nodes for the rows and columns of the
            %replicates
            for row = 1:app.P.pNum(1)
                for col = 1:app.P.pNum(2)
                    elem = sprintf('Row %d, Column %d',row,col); %format string Row x Col y
                    uitreenode(app.PPReplicatesNode, 'Text', convertStringsToChars(elem));
                    uitreenode(app.VSReplicatesNode, "Text", elem);
                    uitreenode(app.MPReplicatesNode, "Text", elem);
                    uitreenode(app.VPReplicatesNode, "Text", elem);
                    uitreenode(app.MSReplicatesNode, "Text", elem);
                    uitreenode(app.evSeqReplicatesNode, "Text", elem);
                end
            end
            
            %populate groupby tab options
            params_size = size(app.Parameters);
            for i = 1:params_size(2)
                uitreenode(app.VPGroupByNode, "Text", app.Parameters{i});
            end
        end
        
        function [row_num, col_num] = wellstring2coords(app, wellstring)
            %this helper function converts a string that represents the
            %position of a well (ex 'A1') in a 96 well plate and outputs
            %the row and column number for that specific well 
            % example: wellstring2coords('A1') = (1,1)
            row_num = find('ABCDEFGH'== wellstring(1));
            col_num = str2double(wellstring(2:end));
        end
        
        function plot_evseq_data(app)
            %This function plots the evSeq bar plot with error bars, this
            %code also creates a table that groups together all the samples
            %with same Variant combo
            app.maxs_AM = evalin('base', 'maxs_AM'); %delete this line later!!!!
            app.EvSeqTable = readtable([app.EvSeqPathName '\' app.EvSeqFileName]);
            bar_plot_data = table('Size',[0 2],'VariableTypes',["string", "cell"],'VariableNames',["VariantCombo", "Values"]); %store data in table
            for row = 1:height(app.EvSeqTable) %iterate through all sample rows in evSeq data and use maxs_AM values to populate the table
                %NEED TO ADD HANDLING FOR MULTIPLE PLATES? 
                [well_row, well_col] = wellstring2coords(app,app.EvSeqTable.('Well'){row});
                if ~any(strcmp(bar_plot_data.('VariantCombo'), app.EvSeqTable.('VariantCombo'){row})) %new variant combo
                    bar_plot_data = [bar_plot_data; {app.EvSeqTable.('VariantCombo'){row} {app.maxs_AM(well_row,well_col,1)}}];
                else %update existing variant combo to be the mean of values
                    %get row number of previous value
                    index = find(strcmp(bar_plot_data.('VariantCombo'), app.EvSeqTable.('VariantCombo'){row}));
                    assignin("base", "zz_bar_plot_data", bar_plot_data); 
                    bar_plot_data(index, ["Values"]) = {{[bar_plot_data{index, "Values"}{1} app.maxs_AM(well_row,well_col,1)]}};
                end
            end
            
            mean_values = zeros(height(bar_plot_data), 1);
            highbar_vals = zeros(height(bar_plot_data), 1);
            lowbar_vals = zeros(height(bar_plot_data), 1);
            for sample=1:height(bar_plot_data) %find the average and standard deviation of the values
                values = bar_plot_data{sample, "Values"}{1};
                mean_values(sample) = mean(values);
                std_dev = std(values);
                highbar_vals(sample) = mean_values(sample) + std_dev;
                lowbar_vals(sample) = mean_values(sample) - std_dev;
            end
            bar_plot_data.('Mean Value') = mean_values;
            bar_plot_data.('Upper Error Bar') = highbar_vals;
            bar_plot_data.('Lower Error Bar') = lowbar_vals;
            bar_plot_data = sortrows(bar_plot_data,'Mean Value','descend'); %sort table in descending order
            cats = categorical(bar_plot_data.('VariantCombo'));
            ordered_cats = reordercats(cats,bar_plot_data.('VariantCombo'));
            hold(app.evSeqPlot,'on')
            evSeq_plot = bar(app.evSeqPlot, ordered_cats, bar_plot_data.('Mean Value'));
            error_bars = errorbar(app.evSeqPlot, ordered_cats,bar_plot_data.('Mean Value'),bar_plot_data.('Upper Error Bar'),bar_plot_data.('Lower Error Bar'), 'k','linestyle','none');
            hold(app.evSeqPlot,'off')
            assignin("base", "zz_bar_plot_data", bar_plot_data);            
        end
        
        function format_dImi(app)
            %format dImi variable to visualize on prepost collapse tab
            
            % unpack images in dImi array and convert to dB
            a=app.dImi{1,:,:};
            app.dImis = nan([size(a) 31 2 96]); % dImis dimensions: zs, xs, pressures, imaging modes, wells
            for pressure = 1:size(app.dImis,3)
                for im_mode = 1:size(app.dImis,4)
                    for well = 1:size(app.dImis,5)
                         app.dImis(:,:,pressure, im_mode,well) = 20*log10(squeeze(abs(app.dImi{pressure,im_mode,well})));
                    end
                end
            end
            
            app.dImis(:,:,:,1,:) = app.dImis(:,:,:,1,:) - min(app.dImis(:,:,:,1,:),[],'all'); % subtract min value from all images
            app.dImis(:,:,:,2,:) = app.dImis(:,:,:,2,:) - min(app.dImis(:,:,:,2,:),[],'all'); % subtract min value from all images
%             app.dImi = permute(reshape(app.dImis, app.N_Frames, 2, app.PlateSize(2), app.PlateSize(1)), [4 3 1 2]);
            
            % get Z indices of sample based on disp_depth
            iZd = find(app.Zi>=app.sample_depth(1)):find(app.Zi>=app.sample_depth(2));
            % get Z indices of noise based on noise_slice
            iZd_noise = find(app.Zi>=app.noise_slice(1)):find(app.Zi>=app.noise_slice(2));
            % calculate noise values for xAM and Bmode
            % dImis dimensions: zs, xs, pressures, imaging modes, wells
            
            % calculate max noise and max intensity across all images at each
            % pressure and for each imaging mode
            noise_xAMs = [];
            max_xAMs = [];
            noise_Bmodes = [];
            max_Bmodes = [];
            for pressure = 1:size(app.dImis,3)
                for im_mode = 1:size(app.dImis,4)
                    if im_mode == 1
                        max_xAMs(pressure) = max(app.dImis(:,:,pressure,im_mode,:), [] ,'all');
                        noise_xAMs(pressure) = max(mean(mean(app.dImis(iZd_noise,:,pressure,im_mode,:)))) - min(squeeze(abs(app.dImis(:,:,pressure,im_mode,:))), [] ,'all');
                    elseif im_mode == 2
                        max_Bmodes(pressure) = max(app.dImis(:,:,pressure,im_mode,:), [] ,'all');
                        noise_Bmodes(pressure) = max(mean(mean(app.dImis(iZd_noise,:,pressure,im_mode,:)))) - min(squeeze(abs(app.dImis(:,:,pressure,im_mode,:))), [] ,'all');
                    end
                end
            end
            
            app.dImi = squeeze(abs(app.dImis(iZd,:,:,:,:))); % crop images
            
            size_DImi = size(app.dImi);
            app.dImgs = nan(100,100,3,size_DImi(3),2,size_DImi(5)); % dImgs dimensions: zs, xs, colors, pressures, imaging mode, wells
            for im_mode = 1:size_DImi(4)
                for pressure = 1:size_DImi(3)
                    for well = 1:size_DImi(5)
                        %resize and convert images to dB
                        temp = imresize(app.dImi(:,:,pressure,im_mode,well), [100 100]); % make 100,100 dimensions modular?                    
                        if im_mode == 1
                            app.dImgs(:,:,:,pressure,im_mode,well) = real2rgb(temp, 'hot', [noise_xAMs(pressure) max_xAMs(pressure)]);
                        end
                        if im_mode == 2
                            app.dImgs(:,:,:,pressure,im_mode,well) = real2rgb(temp, 'bone', [noise_Bmodes(pressure) max_Bmodes(pressure)]);
                        end
                    end
                end
            end
%             app.dImi = permute(reshape(app.dImi, app.N_Frames-1, 2, app.PlateSize(2), app.PlateSize(1)), [4 3 1 2]);            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function
        function UpdateButtonPushed(app, event)
            update(app);
        end

        % Button pushed function: ImportmetadataButton
        function ImportmetadataButtonPushed(app, event)
            [app.MetadataFileName, app.MetadataPathName] = uigetfile({'*.xlsx';'*.csv';'*.xls'},'Select Data Files to Use','MultiSelect','off');
            update(app);
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            [app.Data_directory, ] = uigetdir;
            update(app);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            assignin('base',"pathName", app.Data_directory);
            %scripts to be run
            evalin('base', 'PlateProc1_GUI'); %used to be raw2imgs
            evalin('base', 'autoROI3_GUI'); %used to be imgs2processed
            evalin('base', 'PlateQuan4_split_GUI');

            % pull variables in from base workspace
            app.dImi = evalin('base','dImi');
            app.SampCNRs = evalin('base', 'sampCNRs');
            app.PlateSize = evalin('base', 'PlateSize');
            app.N_Frames = evalin('base', 'Nf');
            app.sample_depth = evalin('base', 'disp_depth');
            app.noise_slice = evalin('base', 'noise_slice');
            app.Zi = evalin('base', 'Zi');
            app.P = evalin('base', 'P');
            
            % format data
%             app.sampCNRs = permute(reshape(sampCNR, app.N_Frames, 2, app.PlateSize(2), app.PlateSize(1)), [4 3 1 2]);
%             app.dImi = 20*log10(squeeze(abs(app.dImi(:,:,:,:)))); % convert to dB
            % unpack images in dImi array and convert to dB
            a=app.dImi{1,:,:};
            app.dImis = nan([size(a) 2 96]);
            for pressure = 1:size(app.dImi,1)-1
                for im_mode = 1:size(app.dImi,2)
                    for well = 1:size(app.dImi,3)
                         app.dImis(:,:,im_mode,well) = 20*log10(squeeze(abs(app.dImi{pressure,im_mode,well})));
                    end
                end
            end
            
            app.dImi(:,:,1,:) = app.dImis(:,:,1,:) - min(app.dImis(:,:,1,:),[],'all'); % subtract min value from all images
            app.dImi(:,:,2,:) = app.dImis(:,:,2,:) - min(app.dImis(:,:,2,:),[],'all'); % subtract min value from all images
%             app.dImi = permute(reshape(app.dImis, app.N_Frames, 2, app.PlateSize(2), app.PlateSize(1)), [4 3 1 2]);
            format_dImi(app)
            
            app.ScanType = 'Voltage Ramp'; %normally this would be set by the Data Acquisition GUI

            plot_prepost_collapse(app);
            plot_microplate_plot(app);
            extract_metadata(app);
            app.GroupByDropDown.Items = [app.GroupByDropDown.Items, app.Parameters];

            if strcmp(app.ScanType, 'Pre-post Collapse')
                app.MicroplateSlider.Visible = 'off';
                app.MicroplateSliderViewer.Visible = 'off';
                return
            end
            populate_trees(app);
            plot_microplate_slider(app);
            plot_voltageramp_slider(app);           
        end

        % Value changed function: GroupByDropDown
        function GroupByDropDownValueChanged(app, event)
            cla(app.VoltagePlot);
            plot_voltage_line(app);
        end

        % Button pushed function: FastPassButton
        function FastPassButtonPushed(app, event)
            % pull variables in from base workspace
            app.dImi = evalin('base','dImi');
            app.SampCNRs = evalin('base', 'sampCNRs');
            app.PlateSize = evalin('base', 'PlateSize');
            app.N_Frames = evalin('base', 'Nf');
            app.sample_depth = evalin('base', 'sample_depth');
            app.noise_slice = evalin('base', 'noise_slice');
            app.Zi = evalin('base', 'Zi');
            app.P = evalin('base', 'P');
            
            % format data
%             app.sampCNRs = permute(reshape(sampCNR, app.N_Frames, 2, app.PlateSize(2), app.PlateSize(1)), [4 3 1 2]);
            format_dImi(app)
            
            app.ScanType = 'Voltage Ramp'; %normally this would be set by the Data Acquisition GUI

            %plot_prepost_collapse(app);
            %plot_microplate_plot(app);
            extract_metadata(app);
            populate_trees(app);
            app.GroupByDropDown.Items = [app.GroupByDropDown.Items, app.Parameters];

            if strcmp(app.ScanType, 'Pre-post Collapse')
                app.MicroplateSlider.Visible = 'off';
                app.MicroplateSliderViewer.Visible = 'off';
                return
            end
            plot_voltage_line(app);
            %plot_microplate_slider(app);
            %plot_voltageramp_slider(app);           
        end

        % Value changed function: MicroplateSlider
        function MicroplateSliderValueChanged(app, event)
            plot_microplate_slider(app);
        end

        % Value changed function: MicroSlideIMDropDown
        function MicroSlideIMDropDownValueChanged(app, event)
            plot_microplate_slider(app);
        end

        % Value changed function: PrePostIMDropDown
        function PrePostIMDropDownValueChanged(app, event)
            plot_prepost_collapse(app);
        end

        % Value changed function: VoltageSliderIMDropDown
        function VoltageSliderIMDropDownValueChanged(app, event)
            plot_voltageramp_slider(app);
        end

        % Button pushed function: ImportevSeqdataButton
        function ImportevSeqdataButtonPushed(app, event)
            [app.EvSeqFileName, app.EvSeqPathName] = uigetfile({'*.csv';'*.xlsx';'*.xls'},'Select Data Files to Use','MultiSelect','off');
            update(app);
            plot_evseq_data(app);
        end

        % Value changed function: MicroplateIMDropDown
        function MicroplateIMDropDownValueChanged(app, event)
            value = app.MicroplateIMDropDown.Value;
            plot_microplate_plot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectFileTab_2 = uitab(app.TabGroup);
            app.SelectFileTab_2.Title = 'Select File';
            app.Panel_2 = uipanel(app.SelectFileTab_2);
            app.SelectDirectoryButton = uibutton(app.Panel_2, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Data Directory';
            app.ImportmetadataButton = uibutton(app.Panel_2, 'push');
            app.ImportmetadataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmetadataButtonPushed, true);
            app.ImportmetadataButton.Text = 'Import metadata';
            app.InfoLabel = uilabel(app.Panel_2);
            app.InfoLabel.Text = {'Data Directory:'; '    *Not yet imported* '; ''; 'Metadata File:'; '    *Not yet imported*'; ''; 'eVSeq File:'; '    *Not yet imported*'};
            app.ImportevSeqdataButton = uibutton(app.Panel_2, 'push');
            app.ImportevSeqdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportevSeqdataButtonPushed, true);
            app.ImportevSeqdataButton.Text = 'Import evSeq data';
            app.ConfirmButton = uibutton(app.SelectFileTab_2, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.FastPassButton = uibutton(app.SelectFileTab_2, 'push');
            app.FastPassButton.ButtonPushedFcn = createCallbackFcn(app, @FastPassButtonPushed, true);
            app.FastPassButton.Text = 'Fast Pass Button (Remove Later)';
            app.PrePostCollapseTab = uitab(app.TabGroup);
            app.PrePostCollapseTab.Title = 'Pre-Post Collapse';
            app.PrePostCollapseFigure = uiaxes(app.PrePostCollapseTab);
            xlabel(app.PrePostCollapseFigure, 'X')
            ylabel(app.PrePostCollapseFigure, 'Y')
            app.ImagingModeDropDown_2Label = uilabel(app.PrePostCollapseTab);
            app.ImagingModeDropDown_2Label.Text = 'Imaging Mode';
            app.PrePostIMDropDown = uidropdown(app.PrePostCollapseTab);
            app.PrePostIMDropDown.Items = {'xAM', 'B mode'};
            app.PrePostIMDropDown.ValueChangedFcn = createCallbackFcn(app, @PrePostIMDropDownValueChanged, true);
            app.PrePostIMDropDown.Value = 'xAM';
            app.PrePostTree = uitree(app.PrePostCollapseTab, 'checkbox');
            app.PPReplicatesNode = uitreenode(app.PrePostTree);
            app.PPReplicatesNode.Text = 'Replicates';
            app.PPavgAllNode = uitreenode(app.PPReplicatesNode);
            app.PPavgAllNode.Text = 'Average All';
            app.PPavgSelectNode = uitreenode(app.PPReplicatesNode);
            app.PPavgSelectNode.Text = 'Average Selected';
            app.VoltageSliderTab = uitab(app.TabGroup);
            app.VoltageSliderTab.Title = 'Voltage Slider';
            app.VoltageSliderPanel = uipanel(app.VoltageSliderTab);
            app.ImagingModeDropDown_2Label_2 = uilabel(app.VoltageSliderTab);
            app.ImagingModeDropDown_2Label_2.Text = 'Imaging Mode';
            app.VoltageSliderIMDropDown = uidropdown(app.VoltageSliderTab);
            app.VoltageSliderIMDropDown.Items = {'xAM', 'B mode'};
            app.VoltageSliderIMDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltageSliderIMDropDownValueChanged, true);
            app.VoltageSliderIMDropDown.Value = 'xAM';
            app.VoltageSliderTree = uitree(app.VoltageSliderTab, 'checkbox');
            app.VSReplicatesNode = uitreenode(app.VoltageSliderTree);
            app.VSReplicatesNode.Text = 'Replicates';
            app.VSavgAllNode = uitreenode(app.VSReplicatesNode);
            app.VSavgAllNode.Text = 'Average All';
            app.VSavgSelectNode = uitreenode(app.VSReplicatesNode);
            app.VSavgSelectNode.Text = 'Average Selected';
            app.VoltagePlotTab = uitab(app.TabGroup);
            app.VoltagePlotTab.Title = 'Voltage Plot';
            app.VoltagePlot = uiaxes(app.VoltagePlotTab);
            app.GroupByDropDownLabel = uilabel(app.VoltagePlotTab);
            app.GroupByDropDownLabel.Text = 'Group By';
            app.GroupByDropDown = uidropdown(app.VoltagePlotTab);
            app.GroupByDropDown.Items = {'Columns', 'Rows'};
            app.GroupByDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupByDropDownValueChanged, true);
            app.GroupByDropDown.Value = 'Columns';
            app.LegendLocationDropDownLabel = uilabel(app.VoltagePlotTab);
            app.LegendLocationDropDownLabel.Text = 'Legend Location';
            app.LegendLocationDropDown = uidropdown(app.VoltagePlotTab);
            app.VoltagePlotTree = uitree(app.VoltagePlotTab, 'checkbox');
            app.VPReplicatesNode = uitreenode(app.VoltagePlotTree);
            app.VPReplicatesNode.Text = 'Replicates';
            app.VPavgAllNode = uitreenode(app.VPReplicatesNode);
            app.VPavgAllNode.Text = 'Average All';
            app.VPavgSelectedNode = uitreenode(app.VPReplicatesNode);
            app.VPavgSelectedNode.Text = 'Average Selected';
            app.VPGroupByNode = uitreenode(app.VoltagePlotTree);
            app.VPGroupByNode.Text = 'Group By';
            app.MicroplatePlotTab = uitab(app.TabGroup);
            app.MicroplatePlotTab.Title = 'Microplate Plot';
            app.Microplateplot = uiaxes(app.MicroplatePlotTab);
            title(app.Microplateplot, 'Max signal achieved at any voltage')
            app.MicroplatePlotTree = uitree(app.MicroplatePlotTab, 'checkbox');
            app.MPReplicatesNode = uitreenode(app.MicroplatePlotTree);
            app.MPReplicatesNode.Text = 'Replicates';
            app.MPavgAllNode = uitreenode(app.MPReplicatesNode);
            app.MPavgAllNode.Text = 'Average All';
            app.MPavgSelectNode = uitreenode(app.MPReplicatesNode);
            app.MPavgSelectNode.Text = 'Average Selected';
            app.ImagingModeDropDownLabel_2 = uilabel(app.MicroplatePlotTab);
            app.ImagingModeDropDownLabel_2.Text = 'Imaging Mode';
            app.MicroplateIMDropDown = uidropdown(app.MicroplatePlotTab);
            app.MicroplateIMDropDown.Items = {'xAM', 'B mode'};
            app.MicroplateIMDropDown.ValueChangedFcn = createCallbackFcn(app, @MicroplateIMDropDownValueChanged, true);
            app.MicroplateIMDropDown.Value = 'xAM';
            app.MicroplateSliderTab = uitab(app.TabGroup);
            app.MicroplateSliderTab.Title = 'Microplate Slider';
            app.MicroplateSliderViewer = uiaxes(app.MicroplateSliderTab);
            xlabel(app.MicroplateSliderViewer, 'X')
            ylabel(app.MicroplateSliderViewer, 'Y')
            app.VoltageSliderLabel = uilabel(app.MicroplateSliderTab);
            app.VoltageSliderLabel.Text = 'Voltage Slider';
            app.MicroplateSlider = uislider(app.MicroplateSliderTab);
            app.MicroplateSlider.Limits = [2 25];
            app.MicroplateSlider.ValueChangedFcn = createCallbackFcn(app, @MicroplateSliderValueChanged, true);
            app.MicroplateSlider.MinorTicks = [];
            app.MicroplateSlider.Value = 2;
            app.ImagingModeDropDownLabel = uilabel(app.MicroplateSliderTab);
            app.ImagingModeDropDownLabel.Text = 'Imaging Mode';
            app.MicroSlideIMDropDown = uidropdown(app.MicroplateSliderTab);
            app.MicroSlideIMDropDown.Items = {'xAM', 'B mode'};
            app.MicroSlideIMDropDown.ValueChangedFcn = createCallbackFcn(app, @MicroSlideIMDropDownValueChanged, true);
            app.MicroSlideIMDropDown.Value = 'xAM';
            app.MicroplateSliderTree = uitree(app.MicroplateSliderTab, 'checkbox');
            app.MSReplicatesNode = uitreenode(app.MicroplateSliderTree);
            app.MSReplicatesNode.Text = 'Replicates';
            app.MSavgAllNode = uitreenode(app.MSReplicatesNode);
            app.MSavgAllNode.Text = 'Average All';
            app.MSavgSelectNode = uitreenode(app.MSReplicatesNode);
            app.MSavgSelectNode.Text = 'Average Selected';
            app.SaveDataTab = uitab(app.TabGroup);
            app.SaveDataTab.Title = 'Save Data';
            app.Tree = uitree(app.SaveDataTab, 'checkbox');
            app.FiguresNode = uitreenode(app.Tree);
            app.FiguresNode.Text = 'Figures';
            app.PrepostcollapseNode = uitreenode(app.FiguresNode);
            app.PrepostcollapseNode.Text = 'Pre-post collapse';
            app.VoltageSliderNode = uitreenode(app.FiguresNode);
            app.VoltageSliderNode.Text = 'Voltage Slider';
            app.VoltagePlotNode = uitreenode(app.FiguresNode);
            app.VoltagePlotNode.Text = 'Voltage Plot';
            app.MicroplatePlotNode = uitreenode(app.FiguresNode);
            app.MicroplatePlotNode.Text = 'Microplate Plot';
            app.MicroplateSliderNode = uitreenode(app.FiguresNode);
            app.MicroplateSliderNode.Text = 'Microplate Slider';
            app.evSeqPlotNode = uitreenode(app.FiguresNode);
            app.evSeqPlotNode.Text = 'evSeq Plot';
            app.DataNode = uitreenode(app.Tree);
            app.DataNode.Text = 'Data';
            app.dImiprepostcollapsedataNode = uitreenode(app.DataNode);
            app.dImiprepostcollapsedataNode.Text = 'dImi (pre-post collapse data)';
            app.SampCNRsmicroplatesliderdataNode = uitreenode(app.DataNode);
            app.SampCNRsmicroplatesliderdataNode.Text = 'SampCNRs (microplate slider data)';
            app.maxs_AMMicroplateplotdataNode = uitreenode(app.DataNode);
            app.maxs_AMMicroplateplotdataNode.Text = 'maxs_AM (Microplate plot data)';
            app.PscaninformationNode = uitreenode(app.DataNode);
            app.PscaninformationNode.Text = 'P (scan information)';
            app.dImgsvoltagesliderdataNode = uitreenode(app.DataNode);
            app.dImgsvoltagesliderdataNode.Text = 'dImgs (voltage slider data)';
            app.SaveButton = uibutton(app.SaveDataTab, 'push');
            app.SaveButton.Text = 'Save';
            app.evSeqPlotTab = uitab(app.TabGroup);
            app.evSeqPlotTab.Title = 'evSeq Plot';
            app.evSeqPlot = uiaxes(app.evSeqPlotTab);
            xlabel(app.evSeqPlot, 'Mutants')
            ylabel(app.evSeqPlot, 'Mean Intensity')
            zlabel(app.evSeqPlot, 'Z')
            app.evSeqTree = uitree(app.evSeqPlotTab, 'checkbox');
            app.evSeqReplicatesNode = uitreenode(app.evSeqTree);
            app.evSeqReplicatesNode.Text = 'Replicates';
            app.evSeqavgAllNode = uitreenode(app.evSeqReplicatesNode);
            app.evSeqavgAllNode.Text = 'Average All';
            app.evSeqavgSelectedNode = uitreenode(app.evSeqReplicatesNode);
            app.evSeqavgSelectedNode.Text = 'Average Selected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Processing_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end